classdef main_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FEMLabUIFigure               matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        NewProblemMenu               matlab.ui.container.Menu
        LoadProblemMenu              matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        NotimplementedyetMenu        matlab.ui.container.Menu
        PreprocessorPanel            matlab.ui.container.Panel
        ProblemTypeDropDown          matlab.ui.control.DropDown
        ProblemLocationLabel         matlab.ui.control.Label
        ProblemLocationText          matlab.ui.control.EditField
        GeometryFileButton           matlab.ui.control.Button
        GeometryFileLabel            matlab.ui.control.Label
        GeometryFileText             matlab.ui.control.EditField
        SettingsFilePanel            matlab.ui.container.Panel
        SettingsFileLabel            matlab.ui.control.Label
        SettingsFileText             matlab.ui.control.EditField
        SettingsFileButton           matlab.ui.control.Button
        ElementTypePanel             matlab.ui.container.Panel
        ElementTypeListBox           matlab.ui.control.ListBox
        ElementTypeLabel             matlab.ui.control.Label
        MeshButton                   matlab.ui.control.Button
        MeshLamp                     matlab.ui.control.Lamp
        SolveLamp                    matlab.ui.control.Lamp
        SolveButton                  matlab.ui.control.Button
        ProblemTypeLabel             matlab.ui.control.Label
        SavesetupButton              matlab.ui.control.Button
        UsepregeneratedmeshCheckBox  matlab.ui.control.CheckBox
        MeshFileLabel                matlab.ui.control.Label
        MeshFileText                 matlab.ui.control.EditField
        MeshFileButton               matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        SaveSetupLamp                matlab.ui.control.Lamp
        PostprocessorPanel           matlab.ui.container.Panel
        PostprocessorButton          matlab.ui.control.Button
        Label_2                      matlab.ui.control.Label
        PostprocessorLamp            matlab.ui.control.Lamp
        LogPanel                     matlab.ui.container.Panel
        LogArea                      matlab.ui.control.TextArea
        ClearlogButton               matlab.ui.control.Button
        SavetofileButton             matlab.ui.control.Button
        FEMLabButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        result_file = '';
        mesh_file = '';
        geometry_file = '';
        settings_file = '';
        problem_location = '';
        setup_saved = 0;
        image_state = 0;
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)


        function setup_updated_action(app)
            % Changes in problem setup after setup was saved
            if app.setup_saved == 1
                app.SaveSetupLamp.Color = [1,1,0];
                app.MeshButton.Enable = 'off';
                
                app.MeshLamp.Color = [1,1,0];
                app.MeshLamp.Enable = 'off';
                app.MeshButton.Enable = 'off';
                
                app.SolveLamp.Color = [1,1,0];
                app.SolveLamp.Enable = 'off';
                app.SolveButton.Enable = 'off';
                
                app.PostprocessorButton.Enable = 'off';
                app.PostprocessorLamp.Color = [1,1,0];
                app.PostprocessorLamp.Enable = 'off';
            end
        end
    
        function reset_preprocessor_ui(app)
            app.ProblemLocationText.Value = '';
            app.GeometryFileText.Value = '';
            app.SettingsFileText.Value = '';
            
            app.UsepregeneratedmeshCheckBox.Value = 0;
            app.MeshFileButton.Enable = 'off';
            app.MeshFileText.Value = '';
            app.MeshFileText.Enable = 'off';
            
            app.SaveSetupLamp.Color = [1,1,0];
            
            app.MeshButton.Enable = 'off';
            app.MeshLamp.Color = [1,1,0];
            app.MeshLamp.Enable = 'off';
            
            app.SolveButton.Enable = 'off';
            app.SolveLamp.Color = [1,1,0];
            app.SolveLamp.Enable = 'off';
            
            app.PostprocessorButton.Enable = 'off';
            app.PostprocessorLamp.Color = [1,1,0];
            app.PostprocessorLamp.Enable = 'off';
            
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            close all
            clc
            
            % Assign FEM-types to a GUI-specific number. e.g. a First order triangle is set to the number 1 etc.
            
            app.ElementTypeListBox.ItemsData = Misc.supported_mesh_orders;
            
            % Fill Drop-Down list for porblem types
            app.ProblemTypeDropDown.Items = keys(Misc.supported_problem_types);
            
            % Global flag indicating that the graphical user interface is used.
            global ui;
            ui = true;
            
            % The 'Misc' entity has functions implemented allowing simultaneous writing to console and log area in the GUI in UI mode
            % or only to console in console mode. For writing to the GUI Log-area it needs a reference to it. See Misc.print_message()
            % for further information.
            global msg_dest;
            msg_dest = app.LogArea;
            
            % Preload pictures of logo
            app.FEMLabButton.Icon = 'dummy_1.png';
            app.FEMLabButton.Icon = 'dummy.png';
            
            
        end

        % Value changed function: UsepregeneratedmeshCheckBox
        function UsepregeneratedmeshCheckBoxValueChanged(app, event)
            setup_updated_action(app);
            
            value = app.UsepregeneratedmeshCheckBox.Value;
            if value == 1
                app.MeshFileText.Enable = 'on';
                app.MeshFileButton.Enable = 'on';
            else
                app.MeshFileText.Enable = 'off';
                app.MeshFileButton.Enable = 'off';
            end
        end

        % Button pushed function: GeometryFileButton
        function GeometryFileButtonPushed(app, event)
            setup_updated_action(app);

            [app.geometry_file, app.problem_location] = uigetfile('*.geo','Select geometry file');
            
            % If no file was chosen
            if app.geometry_file == 0
                return
            end
            
            app.ProblemLocationText.Value = app.problem_location;
            app.GeometryFileText.Value = app.geometry_file;
            
        end

        % Button pushed function: SettingsFileButton
        function SettingsFileButtonPushed(app, event)
            setup_updated_action(app);
            
            tmp = pwd;
            
            if ~isempty(app.problem_location)
                cd(app.problem_location)
            end
            [app.settings_file, ~] = uigetfile('*.set','Select settings file');
            
            % If no file was chosen
            if app.settings_file == 0
                cd(tmp)
                return
            end
            
            app.SettingsFileText.Value = app.settings_file;
            cd(tmp)
        end

        % Button pushed function: MeshFileButton
        function MeshFileButtonPushed(app, event)
            tmp = pwd;
             if ~isempty(app.problem_location)
                cd(app.problem_location)
             end
            [app.mesh_file, ~] = uigetfile('*.msh','Select mesh file');
            
            % If no file was chosen
            if app.mesh_file == 0
                cd(tmp)
                return
            end
            
            app.MeshFileText.Value = app.mesh_file;
            cd(tmp)
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            
            success = Parser.parse(app.problem_location);
            if ~success
                return
            end
            
            success = Solver.solve(app.problem_location);
            if ~success
                app.SolveLamp.Color = [1,0,0];
                return
            end
            
            app.SolveLamp.Color = [0,1,0];
            
            
            app.PostprocessorButton.Enable = 'on';
            app.PostprocessorLamp.Color = [1,1,0];
            app.PostprocessorLamp.Enable = 'on';
            
        end

        % Button pushed function: MeshButton
        function MeshButtonPushed(app, event)
            success = GmshIF.mesh(app.problem_location);
            if ~success
                app.MeshLamp.Color = [1,0,0];
                return
            end
            
            app.MeshLamp.Color = [0,1,0];
            app.SolveButton.Enable = 'on';
            app.SolveLamp.Enable = 'on';
        end

        % Menu selected function: LoadProblemMenu
        function LoadProblemButtonPushed(app, event)
            reset_preprocessor_ui(app);
            
            
            app.problem_location = uigetdir(pwd, 'Select problem location');
            
            % If no folder was chosen
            if app.problem_location == 0
                return
            end
            
            app.setup_saved = 1;
            
            [success, computation_step] = Setup.load_setup(app.problem_location);
            if ~success
                return
            end
            
            tmp = pwd;
            cd(app.problem_location)
            
            load('problem_setup.mat', 'problem_setup')
            
            loading_finished = false;
            loading_step = 1;
            
            while ~loading_finished
            
                switch loading_step
                    case Setup.setup_state_initializing_finished
                        
                        if loading_step == computation_step
                            loading_finished = true;
                        end
                        
                        app.geometry_file = problem_setup.geometry_file;
                        app.settings_file = problem_setup.settings_file;
                        app.problem_location = problem_setup.problem_location;
                        
                        app.ProblemLocationText.Value = problem_setup.problem_location;
                        app.GeometryFileText.Value = problem_setup.geometry_file;
                        app.SettingsFileText.Value = problem_setup.settings_file;
                        
                        
                        app.ElementTypeListBox.Value = problem_setup.mesh_order;
                        app.ProblemTypeDropDown.Value = app.ProblemTypeDropDown.Items(problem_setup.problem_type);
                        
                        app.SaveSetupLamp.Color = [0,1,0];
                        
                        if problem_setup.load_pregenerated_mesh
                            app.UsepregeneratedmeshCheckBox.Value = 1;
                            app.MeshFileText.Value = problem_setup.mesh_file;
                            app.MeshFileText.Enable = 'on';
                            app.MeshFileButton.Enable = 'on';
                            
                            app.SolveButton.Enable = 'on';
                            app.SolveLamp.Enable = 'on';
                            
                            app.mesh_file = problem_setup.mesh_file;
                            
                           
                            loading_step = Setup.setup_state_parsing_finished;
                        else
                            app.MeshButton.Enable = 'on';
                            app.MeshLamp.Enable = 'on';
                            
                            loading_step = Setup.setup_state_meshing_finished;
                        end


                    case Setup.setup_state_meshing_finished
                        app.MeshLamp.Color = [0,1,0];
                        app.SolveButton.Enable = 'on';
                        app.SolveLamp.Enable = 'on';
                        
                        if loading_step == computation_step
                            loading_finished = true;
                        end
                        
                        app.mesh_file = problem_setup.mesh_file;
                        
                        loading_step = Setup.setup_state_parsing_finished;
                        
                    case Setup.setup_state_parsing_finished
                        if loading_step == computation_step
                            loading_finished = true;
                        end
                        
                        loading_step = Setup.setup_state_solving_finished;
                        
                    case Setup.setup_state_solving_finished
                        app.SolveLamp.Color = [0,1,0];
                        loading_finished = true;
                        app.PostprocessorButton.Enable = 'on';
                        app.PostprocessorLamp.Enable = 'on';
                        app.PostprocessorLamp.Color = [1,1,0];
                        
                end
            end
            
            cd(tmp);


        end

        % Value changed function: ElementTypeListBox
        function ElementTypeListBoxValueChanged(app, event)
            setup_updated_action(app);
        end

        % Button pushed function: SavesetupButton
        function SavesetupButtonPushed(app, event)
            setup_updated_action(app);
            mesh_order = app.ElementTypeListBox.Value;
            problem_type = app.ProblemTypeDropDown.Value;
            
            % Mesh file version Hard-Coded. Might change in future.
            mesh_file_version = 2;
            load_mesh = app.UsepregeneratedmeshCheckBox.Value;
            
            success = Setup.new_setup(app.problem_location, app.geometry_file, app.settings_file, ...
                    mesh_order, problem_type, mesh_file_version, load_mesh, app.mesh_file);
                
            if ~success
                app.SaveSetupLamp.Color = [1,0,0];
                return
            end
            
            app.SaveSetupLamp.Color = [0,1,0];
            app.setup_saved = 1;
            
            if load_mesh
                app.SolveButton.Enable = 'on';
                app.SolveLamp.Enable = 'on';
                app.MeshLamp.Color = [0,1,0];
            else
                app.MeshButton.Enable = 'on';
                app.MeshLamp.Enable = 'on';
            end
        end

        % Menu selected function: NewProblemMenu
        function NewProblemMenuSelected(app, event)
            app.reset_preprocessor_ui();
        end

        % Button pushed function: FEMLabButton
        function FEMLabButtonPushed(app, event)
            if app.image_state == 0
                app.image_state = 1;
                app.FEMLabButton.Icon = 'dummy_1.png';
            else
                app.image_state = 0;
                app.FEMLabButton.Icon = 'dummy.png';
            end
        end

        % Button pushed function: PostprocessorButton
        function PostprocessorButtonPushed(app, event)
            success = Postprocessor.run(app.problem_location);
            if ~success
                app.PostprocessorLamp.Color = [1,0,0];
                return
            end
            
            app.PostprocessorLamp.Color = [0,1,0];
            
        end

        % Button pushed function: ClearlogButton
        function ClearlogButtonPushed(app, event)
            app.LogArea.Value = '';
        end

        % Button pushed function: SavetofileButton
        function SavetofileButtonPushed(app, event)
            [file, path] = uiputfile('*.log', 'Chose log file');
            
            % If no file was chosen
            if file == 0
                return
            end
            
            log_file = fopen(fullfile(path, file), 'w');
            
            if log_file == -1
                msg = sprintf('Specified log-file %s is not a valid file path.', fullfile(path, file));
                Misc.print_error_message(msg);
                return
            end
            
            str = '';
            for k = 1 : length(app.LogArea.Value)
                str = [str, '\n', app.LogArea.Value{k}];
            end
            fprintf(log_file, str);
            fclose(log_file);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FEMLabUIFigure = uifigure;
            app.FEMLabUIFigure.Name = 'FEMLab';
            app.FileMenu = uimenu(app.FEMLabUIFigure);
            app.FileMenu.Text = 'File';
            app.NewProblemMenu = uimenu(app.FileMenu);
            app.NewProblemMenu.MenuSelectedFcn = createCallbackFcn(app, @NewProblemMenuSelected, true);
            app.NewProblemMenu.Accelerator = 'N';
            app.NewProblemMenu.Text = 'New Problem';
            app.LoadProblemMenu = uimenu(app.FileMenu);
            app.LoadProblemMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadProblemButtonPushed, true);
            app.LoadProblemMenu.Accelerator = 'L';
            app.LoadProblemMenu.Text = 'Load Problem';
            app.HelpMenu = uimenu(app.FEMLabUIFigure);
            app.HelpMenu.Text = 'Help';
            app.NotimplementedyetMenu = uimenu(app.HelpMenu);
            app.NotimplementedyetMenu.Text = 'Not implemented yet';
            app.PreprocessorPanel = uipanel(app.FEMLabUIFigure);
            app.PreprocessorPanel.Title = 'Preprocessor';
            app.ProblemTypeDropDown = uidropdown(app.PreprocessorPanel);
            app.ProblemTypeDropDown.Items = {};
            app.ProblemTypeDropDown.Value = {};
            app.ProblemLocationLabel = uilabel(app.PreprocessorPanel);
            app.ProblemLocationLabel.Text = 'Problem location';
            app.ProblemLocationText = uieditfield(app.PreprocessorPanel, 'text');
            app.ProblemLocationText.Editable = 'off';
            app.GeometryFileButton = uibutton(app.PreprocessorPanel, 'push');
            app.GeometryFileButton.ButtonPushedFcn = createCallbackFcn(app, @GeometryFileButtonPushed, true);
            app.GeometryFileButton.Text = 'Select geometry';
            app.GeometryFileLabel = uilabel(app.PreprocessorPanel);
            app.GeometryFileLabel.Text = 'Geometry file';
            app.GeometryFileText = uieditfield(app.PreprocessorPanel, 'text');
            app.GeometryFileText.Editable = 'off';
            app.SettingsFilePanel = uipanel(app.PreprocessorPanel);
            app.SettingsFileLabel = uilabel(app.SettingsFilePanel);
            app.SettingsFileLabel.Text = 'Settings file';
            app.SettingsFileText = uieditfield(app.SettingsFilePanel, 'text');
            app.SettingsFileText.Editable = 'off';
            app.SettingsFileButton = uibutton(app.SettingsFilePanel, 'push');
            app.SettingsFileButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsFileButtonPushed, true);
            app.SettingsFileButton.Text = 'Select settings';
            app.ElementTypePanel = uipanel(app.PreprocessorPanel);
            app.ElementTypeListBox = uilistbox(app.ElementTypePanel);
            app.ElementTypeListBox.Items = {'First order triangle', 'Second order triangle', 'Third order triangle'};
            app.ElementTypeListBox.ValueChangedFcn = createCallbackFcn(app, @ElementTypeListBoxValueChanged, true);
            app.ElementTypeListBox.Value = 'First order triangle';
            app.ElementTypeLabel = uilabel(app.ElementTypePanel);
            app.ElementTypeLabel.Text = 'Select element-type';
            app.MeshButton = uibutton(app.PreprocessorPanel, 'push');
            app.MeshButton.ButtonPushedFcn = createCallbackFcn(app, @MeshButtonPushed, true);
            app.MeshButton.Enable = 'off';
            app.MeshButton.Text = 'Mesh';
            app.MeshLamp = uilamp(app.PreprocessorPanel);
            app.MeshLamp.Enable = 'off';
            app.SolveLamp = uilamp(app.PreprocessorPanel);
            app.SolveLamp.Enable = 'off';
            app.SolveButton = uibutton(app.PreprocessorPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Enable = 'off';
            app.SolveButton.Text = 'Solve';
            app.ProblemTypeLabel = uilabel(app.PreprocessorPanel);
            app.ProblemTypeLabel.Text = 'Problem type';
            app.SavesetupButton = uibutton(app.PreprocessorPanel, 'push');
            app.SavesetupButton.ButtonPushedFcn = createCallbackFcn(app, @SavesetupButtonPushed, true);
            app.SavesetupButton.Text = 'Save setup';
            app.UsepregeneratedmeshCheckBox = uicheckbox(app.PreprocessorPanel);
            app.UsepregeneratedmeshCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsepregeneratedmeshCheckBoxValueChanged, true);
            app.UsepregeneratedmeshCheckBox.Text = 'Use pregenerated mesh';
            app.MeshFileLabel = uilabel(app.PreprocessorPanel);
            app.MeshFileLabel.Text = 'Mesh file';
            app.MeshFileText = uieditfield(app.PreprocessorPanel, 'text');
            app.MeshFileText.Editable = 'off';
            app.MeshFileText.Enable = 'off';
            app.MeshFileButton = uibutton(app.PreprocessorPanel, 'push');
            app.MeshFileButton.ButtonPushedFcn = createCallbackFcn(app, @MeshFileButtonPushed, true);
            app.MeshFileButton.Enable = 'off';
            app.MeshFileButton.Text = 'Select mesh file';
            app.Label = uilabel(app.PreprocessorPanel);
            app.Label.Text = '';
            app.SaveSetupLamp = uilamp(app.PreprocessorPanel);
            app.PostprocessorPanel = uipanel(app.FEMLabUIFigure);
            app.PostprocessorPanel.Title = 'Postprocessor';
            app.PostprocessorButton = uibutton(app.PostprocessorPanel, 'push');
            app.PostprocessorButton.ButtonPushedFcn = createCallbackFcn(app, @PostprocessorButtonPushed, true);
            app.PostprocessorButton.Enable = 'off';
            app.PostprocessorButton.Text = 'Run';
            app.Label_2 = uilabel(app.PostprocessorPanel);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '';
            app.PostprocessorLamp = uilamp(app.PostprocessorPanel);
            app.PostprocessorLamp.Enable = 'off';
            app.LogPanel = uipanel(app.FEMLabUIFigure);
            app.LogPanel.Title = 'Log';
            app.LogArea = uitextarea(app.LogPanel);
            app.LogArea.Editable = 'off';
            app.LogArea.Value = {''; ''};
            app.ClearlogButton = uibutton(app.LogPanel, 'push');
            app.ClearlogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlogButtonPushed, true);
            app.ClearlogButton.Text = 'Clear log';
            app.SavetofileButton = uibutton(app.LogPanel, 'push');
            app.SavetofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofileButtonPushed, true);
            app.SavetofileButton.Text = 'Save to file';
            app.FEMLabButton = uibutton(app.FEMLabUIFigure, 'push');
            app.FEMLabButton.ButtonPushedFcn = createCallbackFcn(app, @FEMLabButtonPushed, true);
            app.FEMLabButton.Icon = 'dummy.png';
            app.FEMLabButton.Text = 'FEM Lab';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main_window

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FEMLabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FEMLabUIFigure)
        end
    end
end